classdef TumorDetectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TextArea               matlab.ui.control.TextArea
        TumorDetectionButton   matlab.ui.control.Button
        EdgeDetectionButton    matlab.ui.control.Button
        MedianFilteringButton  matlab.ui.control.Button
        UPLOADButton           matlab.ui.control.Button
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
    img1        % Original image loaded by user
    imgFiltered % Median filtered image
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
                [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
    if isequal(file,0)
        uialert(app.UIFigure, 'No file selected', 'Warning');
        return;
    end
    img = imread(fullfile(path, file));
    app.img1 = im2double(img); % Store image in private property
    imshow(app.img1, 'Parent', app.UIAxes);
    title(app.UIAxes, 'MRI Image');
        end

        % Button pushed function: MedianFilteringButton
        function MedianFilteringButtonPushed(app, event)
                if isempty(app.img1)
        uialert(app.UIFigure, 'Please upload an image first.', 'Error');
        return;
    end
    imgGray = app.img1;
    if size(imgGray, 3) == 3
        imgGray = rgb2gray(imgGray);
    end
    app.imgFiltered = medfilt2(imgGray);
    imshow(app.imgFiltered, 'Parent', app.UIAxes2);
    title(app.UIAxes2, 'Median Filtered Image');
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
                if isempty(app.imgFiltered)
        uialert(app.UIFigure, 'Please apply median filtering first.', 'Error');
        return;
    end
    K = app.imgFiltered;
    C = double(K);
    B = zeros(size(C)-2);

    for i = 1:size(C,1)-2
        for j = 1:size(C,2)-2
            % Sobel mask for X-direction
            Gx = (2*C(i+2,j+1)+C(i+2,j)+C(i+2,j+2)) - (2*C(i,j+1)+C(i,j)+C(i,j+2));
            % Sobel mask for Y-direction
            Gy = (2*C(i+1,j+2)+C(i,j+2)+C(i+2,j+2)) - (2*C(i+1,j)+C(i,j)+C(i+2,j));
            % Gradient magnitude
            B(i,j) = sqrt(Gx^2 + Gy^2);
        end
    end
    imshow(B, 'Parent', app.UIAxes3);
    title(app.UIAxes3, 'Edge Detection');
        end

        % Button pushed function: TumorDetectionButton
        function TumorDetectionButtonPushed(app, event)
             if isempty(app.imgFiltered)
        uialert(app.UIFigure, 'Please apply median filtering first.', 'Error');
        return;
    end

    K = app.imgFiltered;
    bw = imbinarize(K, 0.7);
    label = bwlabel(bw);

    stats = regionprops(label, 'Solidity', 'Area');
    solidity = [stats.Solidity];
    area = [stats.Area];

    highDenseIdx = solidity > 0.5;
    if ~any(highDenseIdx)
        uialert(app.UIFigure, 'No tumor-like regions detected.', 'Info');
        return;
    end

    maxArea = max(area(highDenseIdx));
    tumorLabel = find(area == maxArea);
    tumor = ismember(label, tumorLabel);

    se = strel('square', 5);
    tumor = imdilate(tumor, se);

    Bound = bwboundaries(tumor, 'noholes');

    imshow(K, 'Parent', app.UIAxes4);
    hold(app.UIAxes4, 'on');
    for k = 1:length(Bound)
        boundary = Bound{k};
        plot(app.UIAxes4, boundary(:,2), boundary(:,1), 'y', 'LineWidth', 1.75);
    end
    hold(app.UIAxes4, 'off');
    title(app.UIAxes4, 'Tumor Detected');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UPLOADButton = uibutton(app.UIFigure, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.MedianFilteringButton = uibutton(app.UIFigure, 'push');
            app.MedianFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilteringButtonPushed, true);
            app.MedianFilteringButton.Text = 'Median Filtering';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.TumorDetectionButton = uibutton(app.UIFigure, 'push');
            app.TumorDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @TumorDetectionButtonPushed, true);
            app.TumorDetectionButton.Text = 'Tumor Detection';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'         Tumor Detection GUI Using MATLAB'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TumorDetectionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end